classdef LengthScale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        CombSSCandSBBLabel              matlab.ui.control.Label
        CombRSCandSSCLabel              matlab.ui.control.Label
        CombandRSConlyLabel             matlab.ui.control.Label
        n_scGauge                       matlab.ui.control.SemicircularGauge
        n_scGaugeLabel                  matlab.ui.control.Label
        nmLabel                         matlab.ui.control.Label
        nm2Label_2                      matlab.ui.control.Label
        DegreeofPolymerizationofBackbonen_bEditField  matlab.ui.control.NumericEditField
        DegreeofPolymerizationofBackbonen_bEditFieldLabel  matlab.ui.control.Label
        BytheTewGroupUniversityofMassachussettsAmherst2023Label  matlab.ui.control.Label
        nm2Label                        matlab.ui.control.Label
        EffectiveKuhnLengthb_kEditField  matlab.ui.control.NumericEditField
        EffectiveKuhnlengthb_kLabel     matlab.ui.control.Label
        R_bb2EditField                  matlab.ui.control.NumericEditField
        R_bb2Label                      matlab.ui.control.Label
        R_sc2EditField                  matlab.ui.control.NumericEditField
        R_sc2Label                      matlab.ui.control.Label
        PredictedRegimeEditField        matlab.ui.control.EditField
        PredictedRegimeEditFieldLabel   matlab.ui.control.Label
        ClearGraphButton                matlab.ui.control.Button
        DegreeofPolymerizationbetweenSideChainsn_gEditField  matlab.ui.control.NumericEditField
        DegreeofPolymerizationbetweenSideChainsn_gEditFieldLabel  matlab.ui.control.Label
        BackboneMonomerMassDaEditField  matlab.ui.control.NumericEditField
        BackboneMonomerMassDaEditFieldLabel  matlab.ui.control.Label
        SideChainMonomerLengthnmEditField  matlab.ui.control.NumericEditField
        SideChainMonomerLengthnmEditFieldLabel  matlab.ui.control.Label
        BackboneMonomerLengthnmEditField  matlab.ui.control.NumericEditField
        BackboneMonomerLengthnmEditFieldLabel  matlab.ui.control.Label
        SideChainKuhnLengthnmEditField  matlab.ui.control.NumericEditField
        SideChainKuhnLengthnmEditFieldLabel  matlab.ui.control.Label
        BackboneKuhnLengthnmEditField   matlab.ui.control.NumericEditField
        BackboneKuhnLengthnmEditFieldLabel  matlab.ui.control.Label
        LengthScaleLabel                matlab.ui.control.Label
        SideChainMonomerMassDaEditField  matlab.ui.control.NumericEditField
        SideChainMonomerMassDaEditFieldLabel  matlab.ui.control.Label
        PhiCriticalCrowdingParameterEditField  matlab.ui.control.NumericEditField
        PhiCrowdingParameterLabel       matlab.ui.control.Label
        SideChainDegreeofPolymerizationn_scEditField  matlab.ui.control.NumericEditField
        SideChainDegreeofPolymerizationn_scLabel  matlab.ui.control.Label
        SideChainMonomerDensitygmLEditField  matlab.ui.control.NumericEditField
        SideChainMonomerDensitygmLEditFieldLabel  matlab.ui.control.Label
        SideChainMonomerDropDown        matlab.ui.control.DropDown
        SideChainMonomerDropDownLabel   matlab.ui.control.Label
        BackboneMonomerDensitygmLEditField  matlab.ui.control.NumericEditField
        BackboneMonomerDensitygmLEditFieldLabel  matlab.ui.control.Label
        BackboneMonomerDropDown         matlab.ui.control.DropDown
        BackboneMonomerDropDownLabel    matlab.ui.control.Label
        CalculateRegimeButton           matlab.ui.control.Button
        RegimePlot                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            cla(app.RegimePlot)
        end

        % Button pushed function: CalculateRegimeButton
        function CalculateRegimeButtonPushed(app, event)
%Errors and reseting app
    clear app.PredictedRegimeEditField
    app.n_scGauge.Enable = "on"
    app.n_scGauge.Visible = "on"
    if app.BackboneMonomerDropDown.Value == "Select Backbone"
        error('Please select a backbone monomer')
    end
    
    if app.SideChainMonomerDropDown.Value == "Select Side Chain"
        error('Please select a side chain')
    end
    
    lb = app.BackboneMonomerLengthnmEditField.Value;
    bb = app.BackboneKuhnLengthnmEditField.Value;
    ls = app.SideChainMonomerLengthnmEditField.Value;
    bs = app.SideChainKuhnLengthnmEditField.Value;
    
    if bs > bb
    opts = struct('WindowStyle','modal','Interpreter','tex');
    warning = warndlg('\bf Your side chain is stiffer than your backbone, results may be invalid.',...
       'Warning',opts);
    else
    end

% Defining Variables
    NA = 6.02214E23; %Avogadros Number
    vb = 1E21*app.BackboneMonomerMassDaEditField.Value/...
        (NA*app.BackboneMonomerDensitygmLEditField.Value); %Volume of Backbone
    vs = 1E21*app.SideChainMonomerMassDaEditField.Value/...
        (NA*app.SideChainMonomerDensitygmLEditField.Value); %Volume of Side Chain
    nsc = app.SideChainDegreeofPolymerizationn_scEditField.Value; %Degree of Polymerization for Side Chain
    nb= app.DegreeofPolymerizationofBackbonen_bEditField.Value; % Degree of Polymerization for Backbone
    ng = app.DegreeofPolymerizationbetweenSideChainsn_gEditField.Value; %DP between sidechains

% Calcuting Phi and PhiStar
    inv_phi = (1+(vs*nsc/(vb*ng))); %
    phistar = app.PhiCriticalCrowdingParameterEditField.Value;

% Creating Boundary Regimes
    nmax = 1000* bb^2/bs/ls;
    RES = 100;
    n1 = linspace(1,bs/ls,RES);
    n2 = linspace(bs/ls, (bb^2)/(bs*ls),RES);
    n3 = linspace(bb^2/(bs*ls),nmax,RES);
    n4 = linspace(bs/ls,bb^2/(ls*bs),RES);
    n5 = linspace(1,nmax,RES);

    for i = 1:length(n1)
        CtRSC(i) = ((phistar*lb*ls^2)/vb).*(n1(i))^2;
    end
    for i = 1:length(n2)
        CtSSC(i) = (phistar*ls*bs*lb/vb)*n2(i);
    end
    for i = 1:length(n4)
        SSCtRSC(i) = ((phistar*lb*ls^2)/vb)*n4(i)^2;
    end
    for i = 1:length(n3)
        CtSBB(i)= (phistar*((ls*bs)^0.5)*lb*bb/vb)*n3(i)^(0.5);
        SBBtSSC(i) = (phistar*ls*bs*lb/vb)*n3(i);
    end
    for i = 1:length(n5)
        BB(i) = 1+(vs/vb)*n5(i);
    end

%Plotting Results
hold(app.RegimePlot,"on")


% Plotting
if nsc < bs/ls
    %Plots Comb to RSC Regime
    plot(app.RegimePlot,n1,CtRSC,'b','LineWidth',0.5)

    patch(app.RegimePlot, [n1 n2 n2(end)],...
    [CtRSC CtSSC CtRSC(1)],...
    'b','FaceAlpha',0.25) %COMB REGIME
    
    patch(app.RegimePlot, [n1 n1(end) n1(1)],...
    [CtRSC 2*CtRSC(end) 2*CtRSC(end)],...
    'r','FaceAlpha',0.1) %COMB to RSC REGIME

elseif (bs/ls <= nsc) && (nsc <= (bb^2)/(bs*ls))

        %Plots Comb to SSC and SSC to RSC Regimes
        plot(app.RegimePlot,n2,CtSSC,'b','LineWidth',0.5);
        plot(app.RegimePlot,n4,SSCtRSC,'r--','LineWidth',2);
        
        patch(app.RegimePlot,[n1(1) n1(1) bb/ls bb/ls n2(end) n1(end)],...
        [CtRSC(1) 2*(1+vs/vb*bb/ls) 2*(1+vs/vb*bb/ls) CtSBB(1) CtSBB(1) CtRSC(end)],...
        'r','FaceAlpha',0.1) %RSC REGIME
        
        patch(app.RegimePlot, [bb/ls bb/ls n2(end) n2(end)],...
        [CtSBB(1) 2*(1+vs*n2(end)/vb) 2*(1+n2(end)*vs/vb) CtSBB(1)], ...
        'r','FaceAlpha',0.3) %SSC REGIME
        
        patch(app.RegimePlot, [n1 n2 n3 n3(end)],...
        [CtRSC CtSSC CtSBB CtRSC(1)],...
        'b','FaceAlpha',0.25) %COMB

elseif bb^2/(bs*ls) < nsc

        plot(app.RegimePlot,n3,CtSBB,'b','LineWidth',0.5);
        plot(app.RegimePlot,n3,SBBtSSC,'r--','LineWidth',2);
        plot(app.RegimePlot,n4,SSCtRSC,'r--','LineWidth',2);
        
        patch(app.RegimePlot, [n1 n2 n3 n3(end)],...
        [CtRSC CtSSC CtSBB CtRSC(1)],...
        'b','FaceAlpha',0.25) %COMB
        
        patch(app.RegimePlot, [bb/ls bb/ls n3(end) n3(end) n2(end)],...
        [CtSBB(1) 2*(1+vs*n2(end)/vb) phistar*nmax^2*ls^2*lb/vb SBBtSSC(end) SBBtSSC(1)], ...
        'r','FaceAlpha',0.3) %SSC REGIME
        
        patch(app.RegimePlot,[n1(1) n1(1) bb/ls bb/ls n2(end) n1(end)],...
        [CtRSC(1) 2*(1+vs/vb*bb/ls) 2*(1+vs/vb*bb/ls) CtSBB(1) CtSBB(1) CtRSC(end)],...
        'r','FaceAlpha',0.1) %RSC REGIME
        
        patch(app.RegimePlot,[n3 n3(end)],...
        [SBBtSSC CtSBB(end) ], ...
        'r','FaceAlpha',0.7) %SBB Regime

end

% OPTIONAL - Plots Forbidden Regime

% for i = 1:length(n5)
%     BB(i) = 1+vs*n5(i)/vb;
% end
% for i = 1:length(BB)
%     col(i) = i/100;
% end
% 
% 
% patch(app.RegimePlot,[n5 n5(end) n5(1)],...
%     [BB 10*phistar*nmax^2*ls^2*lb/vb 10*phistar*nmax^2*ls^2*lb/vb],...
%     'w','FaceAlpha',1)

% Calcuation of Radii of Gyration and Effective Kuhn Length

% Regime 1
phiSBB = vb*inv_phi/(sqrt(ls*bs)*lb*bb*sqrt(nsc));
    if nsc < bs/ls
        if inv_phi < (phistar*lb*ls^2)/(vb)*(nsc^2)
            app.PredictedRegimeEditField.Value = 'Comb';
            app.R_sc2EditField.Value = bs*ls*nsc;
            app.R_bb2EditField.Value = bs*ls*nb;
            app.EffectiveKuhnLengthb_kEditField.Value = bb;
    
        else app.PredictedRegimeEditField.Value = 'RSC';
            app.R_sc2EditField.Value = ls*nsc;
            app.R_bb2EditField.Value = ls*lb*nsc*nb;
            app.EffectiveKuhnLengthb_kEditField.Value = ls*nsc;
    
            if ls*nsc <= bb
                app.EffectiveKuhnLengthb_kEditField.Value = bb;
            else
                app.EffectiveKuhnLengthb_kEditField.Value = ls*nsc;
            end
        end

% Regime 2
    elseif (bs/ls <= nsc) && (nsc <= (bb^2)/(bs*ls))
        if inv_phi < (phistar*lb*ls*bs)/(vb)*nsc
            app.PredictedRegimeEditField.Value = 'Comb';
            app.R_sc2EditField.Value = bs*ls*nsc;
            app.R_bb2EditField.Value = bs*ls*nb;
            app.EffectiveKuhnLengthb_kEditField.Value = bb;
    
        elseif (bb/ls < nsc) && (inv_phi < ((phistar*lb*ls^2)/vb)*nsc^2)
            app.PredictedRegimeEditField.Value = 'SSC';
            app.R_sc2EditField.Value = inv_phi*vb/lb;
            app.R_bb2EditField.Value = nb*sqrt(vb*lb*inv_phi);
            app.EffectiveKuhnLengthb_kEditField.Value = sqrt(vb*inv_phi/lb);
    
        else
            app.PredictedRegimeEditField.Value = 'RSC';
            app.R_sc2EditField.Value = ls*nsc;
            app.R_bb2EditField.Value = ls*lb*nsc*nb;
    
            if ls*nsc <= bb
                app.EffectiveKuhnLengthb_kEditField.Value = bb;
            else
                app.EffectiveKuhnLengthb_kEditField.Value = ls*nsc;
            end
        end

%Regime 3
        elseif nsc > (bb^2)/(bs*ls)
            if inv_phi < phistar*sqrt(ls*bs)*lb*bb/(vb)*sqrt(nsc)
                app.PredictedRegimeEditField.Value = 'Comb';
                app.R_sc2EditField.Value = bs*ls*nsc;
                app.R_bb2EditField.Value = bs*ls*nb;
                app.EffectiveKuhnLengthb_kEditField.Value = bb;
    
            elseif inv_phi < (phistar*ls*bs*lb/vb)*nsc
                app.PredictedRegimeEditField.Value = 'SBB';
                app.R_sc2EditField.Value = bs*ls*nsc;
                app.R_bb2EditField.Value = lb*bb*nb*phiSBB;
                if ((1/lb)*(vb*(ls*bs)^(-0.5))*inv_phi*(nsc)^(-0.5)) <= bb
                    app.EffectiveKuhnLengthb_kEditField.Value = bb;
                else app.EffectiveKuhnLengthb_kEditField.Value = ...
                        ((1/lb)*(vb*(ls*bs)^(-0.5))*inv_phi*(nsc)^(-0.5));
                end
    
            else app.PredictedRegimeEditField.Value = 'SSC';
                app.R_sc2EditField.Value = inv_phi*vb/lb;
                app.R_bb2EditField.Value = nb*sqrt(vb*lb*inv_phi);
                app.EffectiveKuhnLengthb_kEditField.Value = sqrt(vb*inv_phi/lb);
            end
    
        end

plot(app.RegimePlot,nsc,inv_phi,'k*') %Plots Input Point
axis(app.RegimePlot,[1 nsc*10 CtRSC(1) 10*inv_phi])

% Gauge 
    if bb>bs 
        app.n_scGauge.Limits = [1 2*(bb^2/(bs*ls))];
        app.n_scGauge.Value = nsc;
        app.n_scGauge.MajorTicks = [1 ceil(bs/ls) ceil(bb^2/(bs*ls))];
        app.n_scGauge.ScaleColors = [0 0.45 0.75; 0.5 .20 0.55; 0.65 .08 .18];
        app.n_scGauge.ScaleColorLimits = [1 ceil(bs/ls); ceil(bs/ls) ceil(bb^2/(bs*ls));...
        ceil(bb^2/(bs*ls)) ceil(2*(bb^2/(bs*ls)))];

    else app.n_scGauge.Enable = "off" %Disables Guage if bb<bs 
        app.n_scGauge.Visible = "off"
    end
        end

        % Value changed function: BackboneMonomerDropDown
        function BackboneMonomerDropDownValueChanged(app, event)

app.BackboneMonomerLengthnmEditField.Editable= "off"
app.BackboneKuhnLengthnmEditField.Editable = "off"
app.BackboneMonomerDensitygmLEditField.Editable = "on"
backb = app.BackboneMonomerDropDown.Value;

% Assigning Backbone and Side Chain Monomers

    if backb == "PMMA"
        lb = 0.25; bb = 1.5; db = 0.94;
    else if backb == "PNB"
            lb = 0.61; bb = 1.7; db = 0.955;
    else if backb == "PCO"
            lb = 0.87; bb = 1.3; db = 0.9;
    else if backb == "PCL"
            lb = 0.73; bb = 0.7; db = 1.03;
    else if backb == "PDMS"
            lb = 0.31; bb = 1.3; db = 0.965;
    else if backb == "PnBA"
            lb = 0.25; bb = 1.7; db = 0.89;
    else if backb == "PEG"
            lb = 0.35; bb = 1.1; db = 0.882;
    else if backb == "PS"
            lb = 0.25; bb = 1.8; db = 0.909;
    else if backb == "PLA"
            lb = 0.37; bb = 1.0; db = 1.209;
    else if backb == "PAAM"
            lb = 0.25; bb = 1.8; db = 1.13;
    else if backb == "PNIPAAM"
            lb = 0.25; bb = 0.8; db = 1.1;
    else if backb == "PHEMA"
            lb = 0.25; bb = 1.0; db = 1.07;
    else if backb == "PI"
            lb = 0.42; bb = 0.8; db = 0.681;
    else
        app.BackboneMonomerLengthnmEditField.Editable= "on"
        app.BackboneKuhnLengthnmEditField.Editable = "on"
        app.BackboneMonomerDensitygmLEditField.Editable = "on"
        lb = app.BackboneMonomerLengthnmEditField.Value;
        bb = app.BackboneKuhnLengthnmEditField.Value;
        db = app.BackboneMonomerDensitygmLEditField.Value;

    end
    end
    end
    end
    end
    end
    end
    end
    end
    end
    end
    end
    end


% Displaying density, monomer lenghts on app

app.BackboneMonomerLengthnmEditField.Value = lb;
app.BackboneKuhnLengthnmEditField.Value = bb;
app.BackboneMonomerDensitygmLEditField.Value = db;
        end

        % Value changed function: SideChainMonomerDropDown
        function SideChainMonomerDropDownValueChanged(app, event)
app.SideChainMonomerLengthnmEditField.Editable = "off";
app.SideChainKuhnLengthnmEditField.Editable = "off";
app.SideChainMonomerDensitygmLEditField.Editable = "on";
sidec = app.SideChainMonomerDropDown.Value;

    if sidec == "PMMA"
        ls = 0.25; bs = 1.5; ds = 0.94;
    else if sidec == "PNB"
            ls = 0.61; bs = 1.7; ds = 0.955;
    else if sidec == "PCO"
            ls = 0.87; bs = 1.3; ds = 0.9;
    else if sidec== "PCL"
            ls = 0.73; bs = 0.7; ds = 1.03;
    else if sidec == "PDMS"
            ls = 0.31; bs = 1.3; ds = 0.965;
    else if sidec == "PnBA"
            ls = 0.25; bs = 1.7; ds = 0.89;
    else if sidec == "PEG"
            ls = 0.35; bs = 1.1; ds = 0.882;
    else if sidec == "PS"
            ls = 0.25; bs = 1.8; ds = 0.909;
    else if sidec == "PLA"
            ls = 0.37; bs = 1.0; ds = 1.21;
    else if sidec == "PAAM"
            ls = 0.25; bs = 1.8; ds = 1.13;
    else if sidec == "PNIPAAM"
            ls = 0.25; bs = 0.8; ds = 1.1;
    else if sidec == "PHEMA"
            ls = 0.25; bs = 1.0; ds = 1.07;
    else if sidec == "PI"
            ls = 0.42; bs = 0.8; ds = 0.681;

    else
        app.SideChainMonomerLengthnmEditField.Editable = "on"
        app.SideChainKuhnLengthnmEditField.Editable = "on"
        app.SideChainMonomerDensitygmLEditField.Editable = "on";
        ls = app.SideChainMonomerLengthnmEditField.Value;
        bs = app.SideChainKuhnLengthnmEditField.Value;
        ds = app.SideChainMonomerDensitygmLEditField.Value;

    end
    end
    end
    end
    end
    end
    end
    end
    end
    end
    end
    end
    end
% Displaying density, monomer lengths on app

        app.SideChainMonomerLengthnmEditField.Value = ls;
        app.SideChainKuhnLengthnmEditField.Value = bs;
        app.SideChainMonomerDensitygmLEditField.Value = ds;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegimePlot = uiaxes(app.UIFigure);
            title(app.RegimePlot, 'Diagram of States')
            xlabel(app.RegimePlot, 'n_{sc}')
            ylabel(app.RegimePlot, '{\phi}^{-1}')
            zlabel(app.RegimePlot, 'Z')
            app.RegimePlot.XScale = 'log';
            app.RegimePlot.YScale = 'log';
            app.CalculateRegimeButton = uibutton(app.UIFigure, 'push');
            app.CalculateRegimeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRegimeButtonPushed, true);
            app.CalculateRegimeButton.Text = 'Calculate Regime';
            app.BackboneMonomerDropDownLabel = uilabel(app.UIFigure);
            app.BackboneMonomerDropDownLabel.Text = 'Backbone Monomer';
            app.BackboneMonomerDropDown = uidropdown(app.UIFigure);
            app.BackboneMonomerDropDown.Items = {'Select Backbone', 'PMMA', 'PNB', 'PCO', 'PCL', 'PDMS', 'PnBA', 'PEG', 'PS', 'PLA', 'PAAM', 'PNIPAAM', 'PHEMA', 'PI', 'Other '};
            app.BackboneMonomerDropDown.ValueChangedFcn = createCallbackFcn(app, @BackboneMonomerDropDownValueChanged, true);
            app.BackboneMonomerDropDown.Placeholder = '-Select Backbone-';
            app.BackboneMonomerDropDown.Value = 'Select Backbone';
            app.BackboneMonomerDensitygmLEditFieldLabel = uilabel(app.UIFigure);
            app.BackboneMonomerDensitygmLEditFieldLabel.Text = 'Backbone Monomer Density (g/mL)';
            app.BackboneMonomerDensitygmLEditField = uieditfield(app.UIFigure, 'numeric');
            app.BackboneMonomerDensitygmLEditField.Limits = [0.001 25];
            app.BackboneMonomerDensitygmLEditField.Value = 1;
            app.SideChainMonomerDropDownLabel = uilabel(app.UIFigure);
            app.SideChainMonomerDropDownLabel.Text = 'Side Chain Monomer';
            app.SideChainMonomerDropDown = uidropdown(app.UIFigure);
            app.SideChainMonomerDropDown.Items = {'Select Side Chain', 'PMMA', 'PNB', 'PCO', 'PCL', 'PDMS', 'PnBA', 'PEG', 'PS', 'PLA', 'PAAM', 'PNIPAAM', 'PHEMA', 'PI', 'Other'};
            app.SideChainMonomerDropDown.ValueChangedFcn = createCallbackFcn(app, @SideChainMonomerDropDownValueChanged, true);
            app.SideChainMonomerDropDown.Placeholder = '-Select Side Chain-';
            app.SideChainMonomerDropDown.Value = 'Select Side Chain';
            app.SideChainMonomerDensitygmLEditFieldLabel = uilabel(app.UIFigure);
            app.SideChainMonomerDensitygmLEditFieldLabel.Text = 'Side Chain Monomer Density (g/mL)';
            app.SideChainMonomerDensitygmLEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideChainMonomerDensitygmLEditField.Limits = [0.001 25];
            app.SideChainMonomerDensitygmLEditField.Value = 1;
            app.SideChainDegreeofPolymerizationn_scLabel = uilabel(app.UIFigure);
            app.SideChainDegreeofPolymerizationn_scLabel.Interpreter = 'latex';
            app.SideChainDegreeofPolymerizationn_scLabel.Text = 'Side Chain Degree of Polymerization ($n_{sc}$)';
            app.SideChainDegreeofPolymerizationn_scEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideChainDegreeofPolymerizationn_scEditField.Limits = [1 Inf];
            app.SideChainDegreeofPolymerizationn_scEditField.Value = 10;
            app.PhiCrowdingParameterLabel = uilabel(app.UIFigure);
            app.PhiCrowdingParameterLabel.Interpreter = 'latex';
            app.PhiCrowdingParameterLabel.Text = '\Phi*   (Critical Crowding Parameter) ';
            app.PhiCriticalCrowdingParameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhiCriticalCrowdingParameterEditField.Limits = [0.001 10];
            app.PhiCriticalCrowdingParameterEditField.Value = 0.7;
            app.SideChainMonomerMassDaEditFieldLabel = uilabel(app.UIFigure);
            app.SideChainMonomerMassDaEditFieldLabel.Text = 'Side Chain Monomer Mass (Da)';
            app.SideChainMonomerMassDaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideChainMonomerMassDaEditField.Limits = [1 Inf];
            app.SideChainMonomerMassDaEditField.Value = 100;
            app.LengthScaleLabel = uilabel(app.UIFigure);
            app.LengthScaleLabel.Text = 'LengthScale';
            app.BackboneKuhnLengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.BackboneKuhnLengthnmEditFieldLabel.Text = 'Backbone Kuhn Length (nm)';
            app.BackboneKuhnLengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BackboneKuhnLengthnmEditField.Limits = [0.05 Inf];
            app.BackboneKuhnLengthnmEditField.Editable = 'off';
            app.BackboneKuhnLengthnmEditField.Value = 1;
            app.SideChainKuhnLengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.SideChainKuhnLengthnmEditFieldLabel.Text = 'Side Chain Kuhn Length (nm)';
            app.SideChainKuhnLengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideChainKuhnLengthnmEditField.Limits = [0.05 Inf];
            app.SideChainKuhnLengthnmEditField.Editable = 'off';
            app.SideChainKuhnLengthnmEditField.Value = 1;
            app.BackboneMonomerLengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.BackboneMonomerLengthnmEditFieldLabel.Text = 'Backbone Monomer Length (nm)';
            app.BackboneMonomerLengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BackboneMonomerLengthnmEditField.Limits = [0.05 Inf];
            app.BackboneMonomerLengthnmEditField.Editable = 'off';
            app.BackboneMonomerLengthnmEditField.Value = 1;
            app.SideChainMonomerLengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.SideChainMonomerLengthnmEditFieldLabel.Text = 'Side Chain Monomer Length (nm)';
            app.SideChainMonomerLengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideChainMonomerLengthnmEditField.Limits = [0.05 Inf];
            app.SideChainMonomerLengthnmEditField.Editable = 'off';
            app.SideChainMonomerLengthnmEditField.Value = 1;
            app.BackboneMonomerMassDaEditFieldLabel = uilabel(app.UIFigure);
            app.BackboneMonomerMassDaEditFieldLabel.Text = 'Backbone Monomer Mass (Da)';
            app.BackboneMonomerMassDaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BackboneMonomerMassDaEditField.Limits = [1 Inf];
            app.BackboneMonomerMassDaEditField.Value = 100;
            app.DegreeofPolymerizationbetweenSideChainsn_gEditFieldLabel = uilabel(app.UIFigure);
            app.DegreeofPolymerizationbetweenSideChainsn_gEditFieldLabel.Interpreter = 'latex';
            app.DegreeofPolymerizationbetweenSideChainsn_gEditFieldLabel.Text = 'Degree of Polymerization between Side Chains $(n_g)$';
            app.DegreeofPolymerizationbetweenSideChainsn_gEditField = uieditfield(app.UIFigure, 'numeric');
            app.DegreeofPolymerizationbetweenSideChainsn_gEditField.Limits = [1 Inf];
            app.DegreeofPolymerizationbetweenSideChainsn_gEditField.Value = 1;
            app.ClearGraphButton = uibutton(app.UIFigure, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            app.PredictedRegimeEditFieldLabel = uilabel(app.UIFigure);
            app.PredictedRegimeEditFieldLabel.Interpreter = 'latex';
            app.PredictedRegimeEditFieldLabel.Text = 'Predicted Regime';
            app.PredictedRegimeEditField = uieditfield(app.UIFigure, 'text');
            app.PredictedRegimeEditField.Editable = 'off';
            app.R_sc2Label = uilabel(app.UIFigure);
            app.R_sc2Label.Interpreter = 'latex';
            app.R_sc2Label.Text = '$$<{R_{sc}}^2>$$ ';
            app.R_sc2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_sc2EditField.Limits = [0 Inf];
            app.R_bb2Label = uilabel(app.UIFigure);
            app.R_bb2Label.Interpreter = 'latex';
            app.R_bb2Label.Text = '$$<{R_{bb}}^2>$$';
            app.R_bb2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_bb2EditField.Limits = [0 Inf];
            app.EffectiveKuhnlengthb_kLabel = uilabel(app.UIFigure);
            app.EffectiveKuhnlengthb_kLabel.Interpreter = 'latex';
            app.EffectiveKuhnlengthb_kLabel.Text = 'Effective Kuhn length ($b_k$)';
            app.EffectiveKuhnLengthb_kEditField = uieditfield(app.UIFigure, 'numeric');
            app.nm2Label = uilabel(app.UIFigure);
            app.nm2Label.Interpreter = 'latex';
            app.nm2Label.Text = '$nm^2$';
            app.BytheTewGroupUniversityofMassachussettsAmherst2023Label = uilabel(app.UIFigure);
            app.BytheTewGroupUniversityofMassachussettsAmherst2023Label.Text = {'By Connor L. Witt and Brandon R. Clarke'; 'University of Massachussetts Amherst, 2023'};
            app.DegreeofPolymerizationofBackbonen_bEditFieldLabel = uilabel(app.UIFigure);
            app.DegreeofPolymerizationofBackbonen_bEditFieldLabel.Interpreter = 'latex';
            app.DegreeofPolymerizationofBackbonen_bEditFieldLabel.Text = 'Degree of Polymerization of Backbone $(n_b)$';
            app.DegreeofPolymerizationofBackbonen_bEditField = uieditfield(app.UIFigure, 'numeric');
            app.DegreeofPolymerizationofBackbonen_bEditField.Limits = [1 Inf];
            app.DegreeofPolymerizationofBackbonen_bEditField.Value = 1000;
            app.nm2Label_2 = uilabel(app.UIFigure);
            app.nm2Label_2.Interpreter = 'latex';
            app.nm2Label_2.Text = '$nm^2$';
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Interpreter = 'latex';
            app.nmLabel.Text = '$nm$';
            app.n_scGaugeLabel = uilabel(app.UIFigure);
            app.n_scGaugeLabel.Interpreter = 'latex';
            app.n_scGaugeLabel.Text = '$$ n_{sc} $$';
            app.n_scGauge = uigauge(app.UIFigure, 'semicircular');
            app.CombandRSConlyLabel = uilabel(app.UIFigure);
            app.CombandRSConlyLabel.Text = 'Comb and RSC only';
            app.CombRSCandSSCLabel = uilabel(app.UIFigure);
            app.CombRSCandSSCLabel.Text = 'Comb, RSC, and SSC';
            app.CombSSCandSBBLabel = uilabel(app.UIFigure);
            app.CombSSCandSBBLabel.Text = 'Comb, SSC, and SBB';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Legend.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LengthScale

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end